classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageSteganographyUIFigure     matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        OpenaJPEGimageMenu             matlab.ui.container.Menu
        OpenafiletoembedMenu           matlab.ui.container.Menu
        OpenaJPEGimagewithmessageMenu  matlab.ui.container.Menu
        SavethefinalimageMenu          matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        ToolsMenu                      matlab.ui.container.Menu
        AllfilestatisticsMenu          matlab.ui.container.Menu
        PSNRMenu                       matlab.ui.container.Menu
        ComplexityhistogramMenu        matlab.ui.container.Menu
        ExportstatisticstoCSVMenu      matlab.ui.container.Menu
        ViewMenu                       matlab.ui.container.Menu
        EmbedfileMenu                  matlab.ui.container.Menu
        ExtracthiddenfileMenu          matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        AboutthissoftwareMenu          matlab.ui.container.Menu
        BuildfinalimageButton          matlab.ui.control.Button
        FiletoembedEditFieldLabel      matlab.ui.control.Label
        FiletoembedEditField           matlab.ui.control.EditField
        Lamp                           matlab.ui.control.Lamp
        UIAxes                         matlab.ui.control.UIAxes
        FileSizeEditFieldLabel         matlab.ui.control.Label
        FileSizeEditField              matlab.ui.control.NumericEditField
        ConfigurealgorithmDropDownLabel  matlab.ui.control.Label
        ConfigurealgorithmDropDown     matlab.ui.control.DropDown
        ByteLabel                      matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        CompareButton                  matlab.ui.control.Button
        RetrieveButton                 matlab.ui.control.Button
    end


    properties (Access = private)
        file_to_embed % Filename of the file the user wants to embed
        file_to_embed_name
        file_to_embed_path % Path of file_to_embed
        jpg_image % JPG image name that will contain the file_to_embed
        jpg_image_name
        jpg_image_path % Path of jpgImage
        embedded_image_name %JPG image name with the file_to_embed
        embedded_image_path
        embedded_image
        algorithm_name %Striing of the algorithm name selected
        figure_compare
    end


    methods (Access = private)

        % Menu selected function: OpenaJPEGimageMenu
        function OpenaJPEGimageMenuSelected(app, event)
            app.Lamp.Color = [1 0 0];           
            [app.jpg_image_name,app.jpg_image_path] = uigetfile('*.jpg');
            if  ne(app.jpg_image_name,0)
                % Display the image
                imshow(strcat(app.jpg_image_path,app.jpg_image_name), 'Parent', app.UIAxes) %,'XData', [1 app.UIAxes.Position(3)],'YData', [1 app.UIAxes.Position(4)]);
                title(app.UIAxes, app.jpg_image_name)
                xlabel(app.UIAxes, [])
                ylabel(app.UIAxes, []) 
                
                % Calculate the payload           
                app.jpg_image=JPEGStegoObj([app.jpg_image_path,app.jpg_image_name]);
                Init_Image(app.jpg_image);
                Get_Payload_Capacity_BPCS(app.jpg_image);
                payloadBPCS=app.jpg_image.payloadCapacityBPCS;
                payloadBPCS=payloadBPCS*100;
                countNoiseAreasBPCS=app.jpg_image.countNoiseAreas;
                Get_Payload_Capacity_LSB(app.jpg_image);
                payloadLSB=app.jpg_image.payloadCapacityLSB;
                payloadLSB=payloadLSB*100;
                countLSBAreas=app.jpg_image.countLSBAreas;
                d = {'BPCS',payloadBPCS,countNoiseAreasBPCS;'LSB',payloadLSB,countLSBAreas};
                app.UITable.Data = d;             
            end
            app.Lamp.Color = [0 1 0];
        end

        % Menu selected function: OpenafiletoembedMenu
        function OpenafiletoembedMenuSelected(app, event)
            [app.file_to_embed_name,app.file_to_embed_path] = uigetfile('.txt'); 
            if ne(app.file_to_embed_name,0)
                app.file_to_embed = EmbeddedObj(strcat(app.file_to_embed_path,app.file_to_embed_name));
                app.FiletoembedEditField.Value = app.file_to_embed.name;                                 
                %fileInfo = dir(strcat(app.file_to_embed_path,file_to_embed_name));                          
                %fileSize = fileInfo.bytes; %# The size of the file, in bytes
                app.FileSizeEditField.Value = app.file_to_embed.size; 
                if gt(app.file_to_embed.size,1)
                    app.ByteLabel.Text = 'Bytes';
                end
                
            end
        end

        % Callback function
        function HidefileButtonValueChanged(app, event)
          
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            close all;
            app.delete;
        end

        % Menu selected function: SavethefinalimageMenu
        function SavethefinalimageMenuSelected(app, event)
            [app.embedded_image,app.embedded_image_path] = uiputfile('*.JPG');
        end

        % Button pushed function: BuildfinalimageButton
        function BuildfinalimageButtonPushed(app, event)
            app.Lamp.Color = [1 0 0];
            if isequal(app.jpg_image_name,[]) || isequal(app.jpg_image_name,0)
                f = uifigure;
                uialert(f,'You must select an image','Invalid Selection');
            elseif isequal(app.file_to_embed_name,[]) || isequal(app.file_to_embed_name,0)
                f = uifigure;
                uialert(f,'You must select a file to embed','Invalid Selection');
            else        
                
                if isequal(app.algorithm_name,'BPCS') 
                    Apply_BPCS(app.jpg_image, strcat(app.file_to_embed_path,app.file_to_embed_name));
%                   jpeg_write(app.jpg_image.readData,strcat(app.jpg_image_path,app.jpg_image.name,'-embedded.jpg'));
                    app.embedded_image_path = app.jpg_image_path;
                    app.embedded_image_name = app.jpg_image_name;
                    app.embedded_image=app.jpg_image;
                elseif isequal(app.algorithm_name,'LSB')
                    Apply_LSB(app.jpg_image, strcat(app.file_to_embed_path,app.file_to_embed_name));
                    app.embedded_image_path = app.jpg_image_path;
                    app.embedded_image_name = app.jpg_image_name;
                    app.embedded_image=app.jpg_image;
                else 
                    f = uifigure;
                    uialert(f,'You must select an algorithm','Invalid Selection');
                end
            end
            pause(1);
            app.Lamp.Color = [0 1 0];
        end

        % Value changed function: ConfigurealgorithmDropDown
        function ConfigurealgorithmDropDownValueChanged(app, event)
            app.algorithm_name = app.ConfigurealgorithmDropDown.Value;
        end

        % Button pushed function: CompareButton
        function CompareButtonPushed(app, event)
            if isequal(app.algorithm_name,'BPCS')
                figure('Name','JPG images comparison')
                imshowpair(imread([app.jpg_image_path,app.jpg_image_name]),imread(strcat(app.jpg_image.name,'-embedded-BPCS.jpg')),'montage');
                figure('Name','JPG images difference')
                imshowpair(imread([app.jpg_image_path,app.jpg_image_name]),imread(strcat(app.jpg_image.name,'-embedded-BPCS.jpg')),'diff');
            elseif isequal(app.algorithm_name,'LSB')
                figure('Name','JPG images comparison')
                imshowpair(imread([app.jpg_image_path,app.jpg_image_name]),imread(strcat(app.jpg_image.name,'-embedded-LSB.jpg')),'montage');
                figure('Name','JPG images difference')
                imshowpair(imread([app.jpg_image_path,app.jpg_image_name]),imread(strcat(app.jpg_image.name,'-embedded-LSB.jpg')),'diff');
            end
        end

        % Button pushed function: RetrieveButton
        function RetrieveButtonPushed(app, event)
            app.Lamp.Color = [1 0 0];
            if isequal(app.embedded_image_name,[]) || isequal(app.embedded_image_name,0)
                f = uifigure;
                uialert(f,'You must have selected a JPEG image with an embedded message to perform this','JPEG image missing');
            elseif  isequal(app.algorithm_name,'BPCS')
                Retrieve_Data_from_BPCS(app.embedded_image);
            elseif isequal(app.algorithm_name,'LSB')
                Retrieve_Data_from_LSB(app.embedded_image);
            end
            pause(1);
            app.Lamp.Color = [0 1 0];
        end

        % Menu selected function: OpenaJPEGimagewithmessageMenu
        function OpenaJPEGimagewithmessageMenuSelected(app, event)
            app.Lamp.Color = [1 0 0];
            [app.embedded_image_name,app.embedded_image_path] = uigetfile('*.jpg');
            if  ne(app.embedded_image_name,0)
                % Display the image
                imshow(strcat(app.embedded_image_path,app.embedded_image_name), 'Parent', app.UIAxes) %,'XData', [1 app.UIAxes.Position(3)],'YData', [1 app.UIAxes.Position(4)]);
                title(app.UIAxes, app.embedded_image_name);
                xlabel(app.UIAxes, []);
                ylabel(app.UIAxes, []);
                app.embedded_image=JPEGStegoObj(strcat(app.embedded_image_path,app.embedded_image_name));
            end
            app.Lamp.Color = [0 1 0];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageSteganographyUIFigure = uifigure;
            app.ImageSteganographyUIFigure.Name = 'Image Stegnography';
            app.FileMenu = uimenu(app.ImageSteganographyUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenaJPEGimageMenu = uimenu(app.FileMenu);
            app.OpenaJPEGimageMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenaJPEGimageMenuSelected, true);
            app.OpenaJPEGimageMenu.Accelerator = 'o';
            app.OpenaJPEGimageMenu.Text = 'Open a JPEG image...';
            app.OpenafiletoembedMenu = uimenu(app.FileMenu);
            app.OpenafiletoembedMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenafiletoembedMenuSelected, true);
            app.OpenafiletoembedMenu.Accelerator = 'e';
            app.OpenafiletoembedMenu.Text = 'Open a file to embed...';
            app.OpenaJPEGimagewithmessageMenu = uimenu(app.FileMenu);
            app.OpenaJPEGimagewithmessageMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenaJPEGimagewithmessageMenuSelected, true);
            app.OpenaJPEGimagewithmessageMenu.Text = 'Open a JPEG image with message';
            app.SavethefinalimageMenu = uimenu(app.FileMenu);
            app.SavethefinalimageMenu.MenuSelectedFcn = createCallbackFcn(app, @SavethefinalimageMenuSelected, true);
            app.SavethefinalimageMenu.Enable = 'off';
            app.SavethefinalimageMenu.Separator = 'on';
            app.SavethefinalimageMenu.Accelerator = 'S';
            app.SavethefinalimageMenu.Text = 'Save the final image...';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Accelerator = 'Q';
            app.ExitMenu.Text = 'Exit';
            app.ToolsMenu = uimenu(app.ImageSteganographyUIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.AllfilestatisticsMenu = uimenu(app.ToolsMenu);
            app.AllfilestatisticsMenu.Enable = 'off';
            app.AllfilestatisticsMenu.Text = 'All file statistics';
            app.PSNRMenu = uimenu(app.ToolsMenu);
            app.PSNRMenu.Enable = 'off';
            app.PSNRMenu.Text = 'PSNR';
            app.ComplexityhistogramMenu = uimenu(app.ToolsMenu);
            app.ComplexityhistogramMenu.Enable = 'off';
            app.ComplexityhistogramMenu.Text = 'Complexity histogram';
            app.ExportstatisticstoCSVMenu = uimenu(app.ToolsMenu);
            app.ExportstatisticstoCSVMenu.Enable = 'off';
            app.ExportstatisticstoCSVMenu.Separator = 'on';
            app.ExportstatisticstoCSVMenu.Text = 'Export statistics to CSV...';
            app.ViewMenu = uimenu(app.ImageSteganographyUIFigure);
            app.ViewMenu.Text = 'View';
            app.EmbedfileMenu = uimenu(app.ViewMenu);
            app.EmbedfileMenu.Enable = 'off';
            app.EmbedfileMenu.Accelerator = '1';
            app.EmbedfileMenu.Checked = 'on';
            app.EmbedfileMenu.Text = 'Embed file';
            app.ExtracthiddenfileMenu = uimenu(app.ViewMenu);
            app.ExtracthiddenfileMenu.Enable = 'off';
            app.ExtracthiddenfileMenu.Accelerator = '2';
            app.ExtracthiddenfileMenu.Checked = 'on';
            app.ExtracthiddenfileMenu.Text = 'Extract hidden file';
            app.AboutMenu = uimenu(app.ImageSteganographyUIFigure);
            app.AboutMenu.Text = 'About';
            app.AboutthissoftwareMenu = uimenu(app.AboutMenu);
            app.AboutthissoftwareMenu.Text = 'About this software';
            app.BuildfinalimageButton = uibutton(app.ImageSteganographyUIFigure, 'push');
            app.BuildfinalimageButton.ButtonPushedFcn = createCallbackFcn(app, @BuildfinalimageButtonPushed, true);
            app.BuildfinalimageButton.Text = 'Build final image';
            app.FiletoembedEditFieldLabel = uilabel(app.ImageSteganographyUIFigure);
            app.FiletoembedEditFieldLabel.Text = 'File to embed';
            app.FiletoembedEditField = uieditfield(app.ImageSteganographyUIFigure, 'text');
            app.FiletoembedEditField.Editable = 'off';
            app.Lamp = uilamp(app.ImageSteganographyUIFigure);
            app.UIAxes = uiaxes(app.ImageSteganographyUIFigure);
            title(app.UIAxes, 'Image')
            app.FileSizeEditFieldLabel = uilabel(app.ImageSteganographyUIFigure);
            app.FileSizeEditFieldLabel.Text = 'File Size';
            app.FileSizeEditField = uieditfield(app.ImageSteganographyUIFigure, 'numeric');
            app.FileSizeEditField.Limits = [0 Inf];
            app.FileSizeEditField.Editable = 'off';
            app.ConfigurealgorithmDropDownLabel = uilabel(app.ImageSteganographyUIFigure);
            app.ConfigurealgorithmDropDownLabel.Text = 'Configure algorithm';
            app.ConfigurealgorithmDropDown = uidropdown(app.ImageSteganographyUIFigure);
            app.ConfigurealgorithmDropDown.Items = {'Select algorithm', 'BPCS', 'LSB'};
            app.ConfigurealgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @ConfigurealgorithmDropDownValueChanged, true);
            app.ConfigurealgorithmDropDown.Value = 'Select algorithm';
            app.ByteLabel = uilabel(app.ImageSteganographyUIFigure);
            app.ByteLabel.Text = 'Byte';
            app.UITable = uitable(app.ImageSteganographyUIFigure);
            app.UITable.ColumnName = {'Algorithm'; 'Payload Capacity %'; 'Noise areas'};
            app.UITable.RowName = {};
            app.CompareButton = uibutton(app.ImageSteganographyUIFigure, 'push');
            app.CompareButton.ButtonPushedFcn = createCallbackFcn(app, @CompareButtonPushed, true);
            app.CompareButton.Text = 'Compare';
            app.RetrieveButton = uibutton(app.ImageSteganographyUIFigure, 'push');
            app.RetrieveButton.ButtonPushedFcn = createCallbackFcn(app, @RetrieveButtonPushed, true);
            app.RetrieveButton.Text = 'Retrieve';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageSteganographyUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageSteganographyUIFigure)
        end
    end
end